classdef interfaz_microred < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image2_2            matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        REC                 matlab.ui.control.StateButton
        Button_2            matlab.ui.control.Button
        Module8CheckBox_2   matlab.ui.control.CheckBox
        Module7CheckBox_2   matlab.ui.control.CheckBox
        Module6CheckBox_2   matlab.ui.control.CheckBox
        Module5CheckBox_2   matlab.ui.control.CheckBox
        Module4CheckBox_2   matlab.ui.control.CheckBox
        Module3CheckBox_2   matlab.ui.control.CheckBox
        Module8CheckBox     matlab.ui.control.CheckBox
        Module7CheckBox     matlab.ui.control.CheckBox
        Module6CheckBox     matlab.ui.control.CheckBox
        Module5CheckBox     matlab.ui.control.CheckBox
        Module4CheckBox     matlab.ui.control.CheckBox
        Module3CheckBox     matlab.ui.control.CheckBox
        SetparameterButton  matlab.ui.control.Button
        Module2CheckBox_2   matlab.ui.control.CheckBox
        SignalSpinner       matlab.ui.control.Spinner
        SignalSpinnerLabel  matlab.ui.control.Label
        ModuleSpinner       matlab.ui.control.Spinner
        ModuleSpinnerLabel  matlab.ui.control.Label
        DutyCycleSlider     matlab.ui.control.Slider
        DutyCycleLabel      matlab.ui.control.Label
        Module1CheckBox_2   matlab.ui.control.CheckBox
        Module2CheckBox     matlab.ui.control.CheckBox
        Module1CheckBox     matlab.ui.control.CheckBox
        Button              matlab.ui.control.StateButton
        Image               matlab.ui.control.Image
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Serial object
        LectTimer % Timer object
        PlotLine  % Line object
        PlotLine2  % Line object
    end
    
    methods (Access = private)
        
        function SerialTimerFcn(app,~,~)
            
            global box11 box12 box21 box22 box31 box32 box41 box42
            global box51 box52 box61 box62 box71 box72 box81 box82
            global parar  count flag1 flag2 samples rec contadorT tiempo
            global data_m11 data_m12 data_m21 data_m22 data_m31 data_m32
            global data_m41 data_m42 data_m51 data_m52 data_m61 data_m62
            global data_m71 data_m72 data_m81 data_m82 
                        
            parar=0;samples=400;
            c_volt=0;
            %line=linspace(0,0,samples);
            
            num=16;
            lecturas=zeros(1,num);
            z=zeros(num,2);
                    
            serialData = read(app.s,150,'char'); %lectura Serial        

            flag1=0;flag2=0;
            count=0;coma=0;
            n_ser=0;
            
            while flag1==0
                count=count+1;
                ini=serialData(1,count);
                if ini==';'
                    flag1=1;
                end
            end
            
            while flag2==0
                count=count+1;
                fin=serialData(count);
                
                if fin==';'
                    coma=coma+1;
                end
                
                if coma<16
                    n_ser=n_ser+1;
                    cadena(1,n_ser)=fin;
                else
                    flag2=1;
                end
                
            end
            
            x= strsplit(cadena,';');
            for j=1:num
                    y(j,1)=string(x(1,j));
            end
            
            for j=1:num
                    z(j,:)=strsplit(y(j,1),'#');
            end
            
            for j=1:num
                    lecturas(1,z(j,1))=z(j,2)*0.00080586;
            end
            
            %----Añadir datos a vectores-----------
            
            %lect 1 - M1
            ym11 = app.PlotLine(1,1).YData;
            ym11 = circshift(ym11,1);
            ym11(1) = lecturas(1,1);
            %lect 2 - M1
            ym12 = app.PlotLine2(1,1).YData;
            ym12 = circshift(ym12,1);
            ym12(1) = lecturas(1,2);

            
            %lect 1 - M2
            ym21 = app.PlotLine(2,1).YData;
            ym21 = circshift(ym21,1);
            ym21(1) = lecturas(1,3);
            %lect 2 - M2
            ym22 = app.PlotLine2(2,1).YData;
            ym22 = circshift(ym22,1);
            ym22(1) = lecturas(1,4);

            
            %lect 1 - M3
            ym31 = app.PlotLine(3,1).YData;
            ym31 = circshift(ym31,1);
            ym31(1) = lecturas(1,5);
            %lect 2 - M3
            ym32 = app.PlotLine2(3,1).YData;
            ym32 = circshift(ym32,1);
            ym32(1) = lecturas(1,6);
            
            %lect 1 - M4
            ym41 = app.PlotLine(4,1).YData;
            ym41 = circshift(ym41,1);
            ym41(1) = lecturas(1,7);
            %lect 2 - M4
            ym42 = app.PlotLine2(4,1).YData;
            ym42 = circshift(ym42,1);
            ym42(1) = lecturas(1,8);
            
            %lect 1 - M5
            ym51 = app.PlotLine(5,1).YData;
            ym51 = circshift(ym51,1);
            ym51(1) = lecturas(1,9);
            %lect 2 - M5
            ym52 = app.PlotLine2(5,1).YData;
            ym52 = circshift(ym52,1);
            ym52(1) = lecturas(1,10);
            
            %lect 1 - M6
            ym61 = app.PlotLine(6,1).YData;
            ym61 = circshift(ym61,1);
            ym61(1) = lecturas(1,11);        
            %lect 2 - M6
            ym62 = app.PlotLine2(6,1).YData;
            ym62 = circshift(ym62,1);
            ym62(1) = lecturas(1,12);
            
            %lect 1 - M7
            ym71 = app.PlotLine(7,1).YData;
            ym71 = circshift(ym71,1);
            ym71(1) = lecturas(1,13);
            %lect 2 - M7
            ym72 = app.PlotLine2(7,1).YData;
            ym72 = circshift(ym72,1);
            ym72(1) = lecturas(1,14);
            
            %lect 1 - M8
            ym81 = app.PlotLine(8,1).YData;
            ym81 = circshift(ym81,1);
            ym81(1) = lecturas(1,15);
            %lect 2 - M8
            ym82 = app.PlotLine2(8,1).YData;
            ym82 = circshift(ym82,1);
            ym82(1) = lecturas(1,16);
            
            %---------------plot lectura 1-----------------------
            
            %plot lect 1 - M1
            if(box11==1)
                 app.PlotLine(1,1).YData = ym11;
            else
                app.PlotLine(1,1).YData = zeros(1,400);
            end
            %plot lect 1 - M2
            if(box21==1)
                 app.PlotLine(2,1).YData = ym21;
            else
                app.PlotLine(2,1).YData = zeros(1,400);
            end
            %plot lect 1 - M3
            if(box31==1)
                 app.PlotLine(3,1).YData = ym31;
            else
                app.PlotLine(3,1).YData = zeros(1,400);
            end
            %plot lect 1 - M4
            if(box41==1)
                 app.PlotLine(4,1).YData = ym41;
            else
                app.PlotLine(4,1).YData = zeros(1,400);
            end
            %plot lect 1 - M5
            if(box51==1)
                 app.PlotLine(5,1).YData = ym51;
            else
                app.PlotLine(5,1).YData = zeros(1,400);
            end
            %plot lect 1 - M6
            if(box61==1)
                 app.PlotLine(6,1).YData = ym61;
            else
                app.PlotLine(6,1).YData = zeros(1,400);
            end
            %plot lect 1 - M7
            if(box71==1)
                 app.PlotLine(7,1).YData = ym71;
            else
                app.PlotLine(7,1).YData = zeros(1,400);
            end
            %plot lect 1 - M8
            if(box81==1)
                 app.PlotLine(8,1).YData = ym81;
            else
                app.PlotLine(8,1).YData = zeros(1,400);
            end
                        
            %---------------plot lectura 2-----------------------
            
            %plot lect 2 - M1
            if(box12==1)
                 app.PlotLine2(1,1).YData = ym12;
            else
                app.PlotLine2(1,1).YData = zeros(1,400);
            end
            %plot lect 2 - M2
            if(box22==1)
                 app.PlotLine2(2,1).YData = ym22;
            else
                app.PlotLine2(2,1).YData = zeros(1,400);
            end
            %plot lect 2 - M3
            if(box32==1)
                 app.PlotLine2(3,1).YData = ym32;
            else
                app.PlotLine2(3,1).YData = zeros(1,400);
            end
            %plot lect 2 - M4
            if(box42==1)
                 app.PlotLine2(4,1).YData = ym42;
            else
                 app.PlotLine2(4,1).YData = zeros(1,400);
            end
            %plot lect 2 - M5
            if(box52==1)
                 app.PlotLine2(5,1).YData = ym52;
            else
                 app.PlotLine2(5,1).YData = zeros(1,400);
            end
            %plot lect 2 - M6
            if(box62==1)
                 app.PlotLine2(6,1).YData = ym62;
            else
                 app.PlotLine2(6,1).YData = zeros(1,400);
            end
            %plot lect 2 - M7
            if(box72==1)
                 app.PlotLine2(7,1).YData = ym72;
            else
                 app.PlotLine2(7,1).YData = zeros(1,400);
            end
            %plot lect 2 - M8
            if(box82==1)
                 app.PlotLine2(8,1).YData = ym82;
            else
                 app.PlotLine2(8,1).YData = zeros(1,400);
            end
            
            
            if rec==1
                contadorT=contadorT+1;
                data_m11(contadorT,1)=lecturas(1,1);
                data_m12(contadorT,1)=lecturas(1,2);
                data_m21(contadorT,1)=lecturas(1,3);
                data_m22(contadorT,1)=lecturas(1,4);
                data_m31(contadorT,1)=lecturas(1,5);
                data_m32(contadorT,1)=lecturas(1,6);
                data_m41(contadorT,1)=lecturas(1,7);
                data_m42(contadorT,1)=lecturas(1,8);
                data_m51(contadorT,1)=lecturas(1,9);
                data_m52(contadorT,1)=lecturas(1,10);
                data_m61(contadorT,1)=lecturas(1,11);
                data_m62(contadorT,1)=lecturas(1,12);
                data_m71(contadorT,1)=lecturas(1,13);
                data_m72(contadorT,1)=lecturas(1,14);
                data_m81(contadorT,1)=lecturas(1,15);
                data_m82(contadorT,1)=lecturas(1,16);
                
                tiempo(contadorT,1)=50*(contadorT-1);
            end
            
            
            clearvars cadena;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            global box11 box12 box21 box22 box31 box32 box41 box42
            global box51 box52 box61 box62 box71 box72 box81 box82
            global data_m11 data_m12 data_m21 data_m22 data_m31 data_m32
            global data_m41 data_m42 data_m51 data_m52 data_m61 data_m62
            global data_m71 data_m72 data_m81 data_m82 
            global senal modulo duty line rec contadorT
            senal=1;modulo=1;duty=0; contadorT=0;
            box11=1;box12=1;box21=1;box22=1;
            box31=1;box32=1;box41=1;box42=1;
            box51=1;box52=1;box61=1;box62=1;
            box71=1;box72=1;box81=1;box82=1;
            samples=400;
            
            mypi=raspi('169.254.111.33','pi','raspberry');
            %app.s = serialdev(mypi,'/dev/serial0',115200); %Serial0 RPI
            app.s = serialdev(mypi,'/dev/ttyAMA1',115200); %Serial5 RPI
            app.s.Timeout = 1;
            % Initial plot is all zeros
            line=linspace(0,10000,400);
            line2=zeros(1,400);
            app.PlotLine = plot(app.UIAxes,line,line2,line,line2,line,line2,line,line2,line,line2,line,line2,line,line2,line,line2);;
            app.PlotLine2 = plot(app.UIAxes_2,line,line2,line,line2,line,line2,line,line2,line,line2,line,line2,line,line2,line,line2);
            
            % Create timer object
            app.LectTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.05, ...                     % Period is 50 ms
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.SerialTimerFcn);      % Specify callback function
            

        end

        % Callback function
        function StartButtonPushed(app, event)
%             if strcmp(app.LectTimer.Running, 'off')
%                     start(app.LectTimer);
%             end
        end

        % Callback function
        function StopButtonPushed(app, event)
%             stop(app.LectTimer);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if (value==1)
                app.Button.Icon = 'stop_icon.png';
                if strcmp(app.LectTimer.Running, 'off')
                    start(app.LectTimer);
                end
            end
            
            if (value==0)
                app.Button.Icon = 'start_icon.png';
                stop(app.LectTimer);
            end
        end

        % Value changed function: Module1CheckBox
        function Module1CheckBoxValueChanged(app, event)
            global box11  
            value = app.Module1CheckBox.Value;
            if value==1
                box11=1;
            else
                box11=0;
            end
        end

        % Value changed function: Module2CheckBox
        function Module2CheckBoxValueChanged(app, event)
            global box21 
            value = app.Module2CheckBox.Value;
            if value==1
                box21=1;
            else
                box21=0;
            end            
        end

        % Value changed function: Module1CheckBox_2
        function Module1CheckBox_2ValueChanged(app, event)
            global box12
            value = app.Module1CheckBox_2.Value;
            if value==1
                box12=1;
            else
                box12=0;
            end 
        end

        % Value changed function: Module2CheckBox_2
        function Module2CheckBox_2ValueChanged(app, event)
            global box22
            value = app.Module2CheckBox_2.Value;
            if value==1
                box22=1;
            else
                box22=0;
            end 
        end

        % Value changed function: Module3CheckBox
        function Module3CheckBoxValueChanged(app, event)
            global box31 
            value = app.Module3CheckBox.Value; 
            if value==1
                box31=1;
            else
                box31=0;
            end
        end

        % Value changed function: Module3CheckBox_2
        function Module3CheckBox_2ValueChanged(app, event)
            global box32 
            value = app.Module3CheckBox_2.Value;
            if value==1
                box32=1;
            else
                box32=0;
            end
            
        end

        % Value changed function: Module4CheckBox
        function Module4CheckBoxValueChanged(app, event)
            global box41 
            value = app.Module4CheckBox.Value;
            if value==1
                box41=1;
            else
                box41=0;
            end
        end

        % Value changed function: Module4CheckBox_2
        function Module4CheckBox_2ValueChanged(app, event)
            global box42 
            value = app.Module4CheckBox_2.Value;
            if value==1
                box42=1;
            else
                box42=0;
            end
        end

        % Value changed function: Module5CheckBox
        function Module5CheckBoxValueChanged(app, event)
            global box51
            value = app.Module5CheckBox.Value;
            if value==1
                box51=1;
            else
                box51=0;
            end
        end

        % Value changed function: Module5CheckBox_2
        function Module5CheckBox_2ValueChanged(app, event)
            global box52
            value = app.Module5CheckBox_2.Value;
            if value==1
                box52=1;
            else
                box52=0;
            end
        end

        % Value changed function: Module6CheckBox
        function Module6CheckBoxValueChanged(app, event)
            global box61
            value = app.Module6CheckBox.Value;
            if value==1
                box61=1;
            else
                box61=0;
            end
        end

        % Value changed function: Module6CheckBox_2
        function Module6CheckBox_2ValueChanged(app, event)
            global box62
            value = app.Module6CheckBox_2.Value;
            if value==1
                box62=1;
            else
                box62=0;
            end
        end

        % Value changed function: Module7CheckBox
        function Module7CheckBoxValueChanged(app, event)
            global box71
            value = app.Module7CheckBox.Value;
            if value==1
                box71=1;
            else
                box71=0;
            end
        end

        % Value changed function: Module7CheckBox_2
        function Module7CheckBox_2ValueChanged(app, event)
            global box72
            value = app.Module7CheckBox_2.Value;
            if value==1
                box72=1;
            else
                box72=0;
            end
        end

        % Value changed function: Module8CheckBox
        function Module8CheckBoxValueChanged(app, event)
            global box81
            value = app.Module8CheckBox.Value;
            if value==1
                box81=1;
            else
                box81=0;
            end
        end

        % Value changed function: Module8CheckBox_2
        function Module8CheckBox_2ValueChanged(app, event)
            global box82
            value = app.Module8CheckBox_2.Value;
            if value==1
                box82=1;
            else
                box82=0;
            end
        end

        % Value changing function: DutyCycleSlider
        function DutyCycleSliderValueChanging(app, event)
            global duty;
            changingValue = event.Value;
            duty=round(changingValue);
        end

        % Value changing function: ModuleSpinner
        function ModuleSpinnerValueChanging(app, event)
            global modulo;
            changingValue = event.Value;
            modulo=changingValue;
            
        end

        % Value changing function: SignalSpinner
        function SignalSpinnerValueChanging(app, event)
            global senal;
            changingValue = event.Value;
            senal=changingValue;
            
        end

        % Button pushed function: SetparameterButton
        function SetparameterButtonPushed(app, event)
            global senal modulo duty;
            
            formato='A%d;%d;%d;B';
            str=sprintf(formato,modulo,duty,senal);
            for i=1:4
                write(app.s,str,'char');
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global data_m11 data_m12 data_m21 data_m22 data_m31 data_m32
            global data_m41 data_m42 data_m51 data_m52 data_m61 data_m62
            global data_m71 data_m72 data_m81 data_m82 tiempo T1 T2
            
            lect1_m1=data_m11;lect1_m2=data_m21;lect1_m3=data_m31;lect1_m4=data_m41;
            lect1_m5=data_m51;lect1_m6=data_m61;lect1_m7=data_m71;lect1_m8=data_m81;
            lect2_m1=data_m12;lect2_m2=data_m22;lect2_m3=data_m32;lect2_m4=data_m42;
            lect2_m5=data_m52;lect2_m6=data_m62;lect2_m7=data_m72;lect2_m8=data_m82;
            
            size(data_m11)
            
            T1=table(tiempo,lect1_m1,lect1_m2,lect1_m3,lect1_m4,lect1_m5,lect1_m6,lect1_m7,lect1_m8);
            T2=table(tiempo,lect2_m1,lect2_m2,lect2_m3,lect2_m4,lect2_m5,lect2_m6,lect2_m7,lect2_m8);
            
            save_table= 'C:\Users\maxim\OneDrive\Escritorio\Interfaz grafica';
            table_path_format1 = fullfile(save_table, 'measurement_1_microred.xlsx');
            table_path_format2 = fullfile(save_table, 'measurement_2_microred.xlsx');
            writetable(T1,table_path_format1);
            writetable(T2,table_path_format2);
            
        end

        % Value changed function: REC
        function RECValueChanged(app, event)
            global rec contadorT tiempo
            global data_m11 data_m12 data_m21 data_m22 data_m31 data_m32
            global data_m41 data_m42 data_m51 data_m52 data_m61 data_m62
            global data_m71 data_m72 data_m81 data_m82 
            
            value = app.REC.Value;
            if value==1
                contadorT=0;
                delete('C:\Users\maxim\OneDrive\Escritorio\Interfaz grafica\measurement_1_microred.xlsx');
                delete('C:\Users\maxim\OneDrive\Escritorio\Interfaz grafica\measurement_2_microred.xlsx');
                tiempo=[];
                data_m11=[]; data_m12=[]; data_m21=[]; data_m22=[]; data_m31=[]; data_m32=[];
                data_m41=[]; data_m42=[]; data_m51=[]; data_m52=[]; data_m61=[]; data_m62=[];
                data_m71=[]; data_m72=[]; data_m81=[]; data_m82=[];
                rec=1;
            end
            if value==0
                rec=0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'tiempo (ms)')
            ylabel(app.UIAxes, 'Volltaje')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10000];
            app.UIAxes.YLim = [0 3];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.XTick = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'tiempo (ms)')
            ylabel(app.UIAxes_2, 'Volltaje')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 10000];
            app.UIAxes_2.YLim = [0 3];
            app.UIAxes_2.XDir = 'reverse';
            app.UIAxes_2.XTick = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'mascara.png';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'start_icon.png';
            app.Button.Text = '';
            app.Module1CheckBox = uicheckbox(app.UIFigure);
            app.Module1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module1CheckBoxValueChanged, true);
            app.Module1CheckBox.Text = 'Module 1';
            app.Module1CheckBox.Value = true;
            app.Module2CheckBox = uicheckbox(app.UIFigure);
            app.Module2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module2CheckBoxValueChanged, true);
            app.Module2CheckBox.Text = 'Module 2';
            app.Module2CheckBox.Value = true;
            app.Module1CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module1CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module1CheckBox_2ValueChanged, true);
            app.Module1CheckBox_2.Text = {'Module 1'; ''};
            app.Module1CheckBox_2.Value = true;
            app.DutyCycleLabel = uilabel(app.UIFigure);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleSlider = uislider(app.UIFigure);
            app.DutyCycleSlider.ValueChangingFcn = createCallbackFcn(app, @DutyCycleSliderValueChanging, true);
            app.ModuleSpinnerLabel = uilabel(app.UIFigure);
            app.ModuleSpinnerLabel.Text = 'Module';
            app.ModuleSpinner = uispinner(app.UIFigure);
            app.ModuleSpinner.ValueChangingFcn = createCallbackFcn(app, @ModuleSpinnerValueChanging, true);
            app.ModuleSpinner.Limits = [1 8];
            app.ModuleSpinner.Value = 1;
            app.SignalSpinnerLabel = uilabel(app.UIFigure);
            app.SignalSpinnerLabel.Text = 'Signal';
            app.SignalSpinner = uispinner(app.UIFigure);
            app.SignalSpinner.ValueChangingFcn = createCallbackFcn(app, @SignalSpinnerValueChanging, true);
            app.SignalSpinner.Limits = [1 2];
            app.SignalSpinner.Value = 1;
            app.Module2CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module2CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module2CheckBox_2ValueChanged, true);
            app.Module2CheckBox_2.Text = 'Module 2';
            app.Module2CheckBox_2.Value = true;
            app.SetparameterButton = uibutton(app.UIFigure, 'push');
            app.SetparameterButton.ButtonPushedFcn = createCallbackFcn(app, @SetparameterButtonPushed, true);
            app.SetparameterButton.Text = 'Set parameter';
            app.Module3CheckBox = uicheckbox(app.UIFigure);
            app.Module3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module3CheckBoxValueChanged, true);
            app.Module3CheckBox.Text = 'Module 3';
            app.Module3CheckBox.Value = true;
            app.Module4CheckBox = uicheckbox(app.UIFigure);
            app.Module4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module4CheckBoxValueChanged, true);
            app.Module4CheckBox.Text = 'Module 4';
            app.Module4CheckBox.Value = true;
            app.Module5CheckBox = uicheckbox(app.UIFigure);
            app.Module5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module5CheckBoxValueChanged, true);
            app.Module5CheckBox.Text = 'Module 5';
            app.Module5CheckBox.Value = true;
            app.Module6CheckBox = uicheckbox(app.UIFigure);
            app.Module6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module6CheckBoxValueChanged, true);
            app.Module6CheckBox.Text = 'Module 6';
            app.Module6CheckBox.Value = true;
            app.Module7CheckBox = uicheckbox(app.UIFigure);
            app.Module7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module7CheckBoxValueChanged, true);
            app.Module7CheckBox.Text = 'Module 7';
            app.Module7CheckBox.Value = true;
            app.Module8CheckBox = uicheckbox(app.UIFigure);
            app.Module8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Module8CheckBoxValueChanged, true);
            app.Module8CheckBox.Text = 'Module 8';
            app.Module8CheckBox.Value = true;
            app.Module3CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module3CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module3CheckBox_2ValueChanged, true);
            app.Module3CheckBox_2.Text = 'Module 3';
            app.Module3CheckBox_2.Value = true;
            app.Module4CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module4CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module4CheckBox_2ValueChanged, true);
            app.Module4CheckBox_2.Text = 'Module 4';
            app.Module4CheckBox_2.Value = true;
            app.Module5CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module5CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module5CheckBox_2ValueChanged, true);
            app.Module5CheckBox_2.Text = 'Module 5';
            app.Module5CheckBox_2.Value = true;
            app.Module6CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module6CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module6CheckBox_2ValueChanged, true);
            app.Module6CheckBox_2.Text = 'Module 6';
            app.Module6CheckBox_2.Value = true;
            app.Module7CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module7CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module7CheckBox_2ValueChanged, true);
            app.Module7CheckBox_2.Text = 'Module 7';
            app.Module7CheckBox_2.Value = true;
            app.Module8CheckBox_2 = uicheckbox(app.UIFigure);
            app.Module8CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Module8CheckBox_2ValueChanged, true);
            app.Module8CheckBox_2.Text = 'Module 8';
            app.Module8CheckBox_2.Value = true;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'excel_icon.png';
            app.Button_2.Text = '';
            app.REC = uibutton(app.UIFigure, 'state');
            app.REC.ValueChangedFcn = createCallbackFcn(app, @RECValueChanged, true);
            app.REC.Icon = 'rec_icon.png';
            app.REC.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'legends.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'legends.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_microred

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end