classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PausarButton     matlab.ui.control.StateButton
        modulo8CheckBox  matlab.ui.control.CheckBox
        modulo1CheckBox  matlab.ui.control.CheckBox
        IniciarButton    matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %clear mypi;
            
            mypi=raspi('169.254.111.33','pi','raspberry');
            %app.s = serialdev(mypi,'/dev/serial0',115200);
            app.s = serialdev(mypi,'/dev/ttyAMA1',115200);
            app.s.Timeout = 0.1;
            app.UIAxes.XLim= [0 200];
            app.UIAxes.YLim= [0 5000];
            
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            
            global box1 box8 
            global volt1 volt8 
            global parar  count flag1 flag2 line samples
            global voltaje1 voltaje2 voltaje3 voltaje4
            global voltaje5 voltaje6 voltaje7 voltaje8
            global mod1_lect mod2_lect mod3_lect mod4_lect
            global mod5_lect mod6_lect mod7_lect mod8_lect
            box1=0;box8=0;
            parar=0;samples=200;
            c_volt=0;
            line=linspace(0,samples,samples);
            volt1=zeros(1,samples);volt8=zeros(1,samples);
            voltaje1=zeros(1,samples);
            voltaje8=zeros(1,samples);
            
            while 1
                    serialData = read(app.s,36,'char');
                    flag1=0;flag2=0;
                    count=0;
                    n_ser=0;
                while flag1==0
                    count=count+1;
                    ini=serialData(1,count);
                    if ini=='A'
                        flag1=1;
                    end
                end
    
                while flag2==0
                    count=count+1;
                    fin=serialData(count);
                    if fin~='B'
                        n_ser=n_ser+1;
                        cadena(1,n_ser)=fin;
                    else
                        flag2=1;
                    end
        
                end
                x= strsplit(cadena,';')
                x1=string(x(1,1));
                x2=string(x(1,2));
                y1=strsplit(x1,'#');
                y2=strsplit(x2,'#');
    
                lect1=str2double(y1(1,2));
                lect2=str2double(y2(1,2));
    
                num=str2double(y1(1,1));
                num=uint8(num/10);
    
                if num==1
                    voltaje1=circshift(voltaje1,-1);
                    voltaje1(1,samples)=lect1;
                    if box1==0
                        volt1=zeros(1,samples);
                    else
                        volt1=voltaje1;
                    end
                    mod1_lect(1,1)= lect1;
                    mod1_lect(1,2)= lect2;
                end
    
                if num==2
                    mod2_lect(1,1)= lect1;
                    mod2_lect(1,2)= lect2;
                end
    
                if num==3
                    mod3_lect(1,1)= lect1;
                    mod3_lect(1,2)= lect2;
                end
    
                if num==4
                    mod4_lect(1,1)= lect1;
                    mod4_lect(1,2)= lect2;
                end
    
                if num==5
                    mod5_lect(1,1)= lect1;
                    mod5_lect(1,2)= lect2;
                end
    
                if num==6
                    mod6_lect(1,1)= lect1;
                    mod6_lect(1,2)= lect2;
                end
    
                if num==7
                    mod7_lect(1,1)= lect1;
                    mod7_lect(1,2)= lect2;
                end
    
                if num==8
                    voltaje8=circshift(voltaje8,-1);
                    voltaje8(1,samples)=lect1;
                    if box8==0
                        volt8=zeros(1,samples);
                    else
                        volt8=voltaje8;
                    end
                    mod8_lect(1,1)= lect1;
                    mod8_lect(1,2)= lect2;
                end
                clearvars cadena
                if parar==0
                    plot(app.UIAxes,line,volt1,line,volt8);
                end
                %app.UIAxes.XLim= [0 300];
                %app.UIAxes.YLim= [0 5000];
          
            end
            
            
        end

        % Value changed function: modulo1CheckBox
        function modulo1CheckBoxValueChanged(app, event)
            global box1
            value = app.modulo1CheckBox.Value;
            if value ==1
                box1=1;
            else
                box1=0;
            end
            
        end

        % Value changed function: modulo8CheckBox
        function modulo8CheckBoxValueChanged(app, event)
            global box8
            value = app.modulo8CheckBox.Value;
            if value ==1
                box8=1;
            else
                box8=0;
            end
        end

        % Value changed function: PausarButton
        function PausarButtonValueChanged(app, event)
            global parar
            value = app.PausarButton.Value;
            if value ==1
                parar=1;
            else
                parar=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lectura 1')
            xlabel(app.UIAxes, 'samples')
            ylabel(app.UIAxes, 'voltaje')
            zlabel(app.UIAxes, 'Z')
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.modulo1CheckBox = uicheckbox(app.UIFigure);
            app.modulo1CheckBox.ValueChangedFcn = createCallbackFcn(app, @modulo1CheckBoxValueChanged, true);
            app.modulo1CheckBox.Text = 'modulo 1';
            app.modulo8CheckBox = uicheckbox(app.UIFigure);
            app.modulo8CheckBox.ValueChangedFcn = createCallbackFcn(app, @modulo8CheckBoxValueChanged, true);
            app.modulo8CheckBox.Text = 'modulo 8';
            app.PausarButton = uibutton(app.UIFigure, 'state');
            app.PausarButton.ValueChangedFcn = createCallbackFcn(app, @PausarButtonValueChanged, true);
            app.PausarButton.Text = 'Pausar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end