classdef interfaz_timer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        M82CheckBox         matlab.ui.control.CheckBox
        M72CheckBox         matlab.ui.control.CheckBox
        M62CheckBox         matlab.ui.control.CheckBox
        M52CheckBox         matlab.ui.control.CheckBox
        M42CheckBox         matlab.ui.control.CheckBox
        M32CheckBox         matlab.ui.control.CheckBox
        M81CheckBox         matlab.ui.control.CheckBox
        M71CheckBox         matlab.ui.control.CheckBox
        M61CheckBox         matlab.ui.control.CheckBox
        M51CheckBox         matlab.ui.control.CheckBox
        M41CheckBox         matlab.ui.control.CheckBox
        M31CheckBox         matlab.ui.control.CheckBox
        SetparameterButton  matlab.ui.control.Button
        SealSpinner         matlab.ui.control.Spinner
        SealSpinnerLabel    matlab.ui.control.Label
        MduloSpinner        matlab.ui.control.Spinner
        MduloSpinnerLabel   matlab.ui.control.Label
        DutyCycleSlider     matlab.ui.control.Slider
        DutyCycleLabel      matlab.ui.control.Label
        M22CheckBox         matlab.ui.control.CheckBox
        M12CheckBox         matlab.ui.control.CheckBox
        M21CheckBox         matlab.ui.control.CheckBox
        M11CheckBox         matlab.ui.control.CheckBox
        StartButton         matlab.ui.control.StateButton
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Serial object
        LectTimer % Timer object
        PlotLine  % Line object
        PlotLine2  % Line object
    end
    
    methods (Access = private)
        
        function SerialTimerFcn(app,~,~)
            
            global box11 box12 box21 box22
            global volt1 volt8 
            global parar  count flag1 flag2 line samples
            global voltaje1 voltaje2 voltaje3 voltaje4
            global voltaje5 voltaje6 voltaje7 voltaje8
            global mod1_lect mod2_lect mod3_lect mod4_lect
            global mod5_lect mod6_lect mod7_lect mod8_lect
            
            parar=0;samples=200;
            c_volt=0;
            line=linspace(0,samples,samples);
            volt1=zeros(1,samples);volt8=zeros(1,samples);
            voltaje1=zeros(1,samples);
            voltaje8=zeros(1,samples);
            
            
            serialData = read(app.s,200,'char'); %lectura Serial
            
            flag1=0;flag2=0;
            count=0;
            n_ser=0;
            while flag1==0
                count=count+1;
                ini=serialData(1,count);
                if ini=='A'
                    flag1=1;
                end
            end
    
            while flag2==0
                count=count+1;
                fin=serialData(count);
                if fin~='B'
                    n_ser=n_ser+1;
                    cadena(1,n_ser)=fin;
                else
                    flag2=1;
                end    
            end
            x= strsplit(cadena,';');
            x1=string(x(1,1));
            x2=string(x(1,2));
            y1=strsplit(x1,'#');
            y2=strsplit(x2,'#');

            lect1=str2double(y1(1,2));
            lect2=str2double(y2(1,2));

            num=str2double(y1(1,1));
            num=uint8(num/10);

            if num==1
                mod1_lect(1,1)= lect1;
                mod1_lect(1,2)= lect2;
                
            end

            if num==2
                mod2_lect(1,1)= lect1;
                mod2_lect(1,2)= lect2;
            end

            if num==3
                mod3_lect(1,1)= lect1;
                mod3_lect(1,2)= lect2;
            end

            if num==4
                mod4_lect(1,1)= lect1;
                mod4_lect(1,2)= lect2;
            end

            if num==5
                mod5_lect(1,1)= lect1;
                mod5_lect(1,2)= lect2;
            end

            if num==6
                mod6_lect(1,1)= lect1;
                mod6_lect(1,2)= lect2;
            end

            if num==7
                mod7_lect(1,1)= lect1;
                mod7_lect(1,2)= lect2;
            end

            if num==8
                mod8_lect(1,1)= lect1;
                mod8_lect(1,2)= lect2;
            end
            
            %lect 1 - M1
            ydata11 = app.PlotLine(1,1).YData;
            ydata11 = circshift(ydata11,1);
            ydata11(1) = mod1_lect(1,1);
            %lect 2 - M1
            ydata12 = app.PlotLine(2,1).YData;
            ydata12 = circshift(ydata12,1);
            ydata12(1) = mod1_lect(1,2);
            
            %lect 1 - M2
            ydata21 = app.PlotLine2(1,1).YData;
            ydata21 = circshift(ydata21,1);
            ydata21(1) = mod2_lect(1,1);
            %lect 2 - M2
            ydata22 = app.PlotLine2(2,1).YData;
            ydata22 = circshift(ydata22,1);
            ydata22(1) = mod2_lect(1,2);
            
            %plot lect 1 - M1
            if(box11==1)
                app.PlotLine(1,1).YData = ydata11;
            else
                app.PlotLine(1,1).YData = zeros(1,400);
            end
            %plot lect 2 - M1
            if(box12==1)
                app.PlotLine(2,1).YData = ydata12;
            else
                app.PlotLine(2,1).YData = zeros(1,400);
            end
            
            %plot lect 1 - M2
            if(box21==1)
                app.PlotLine2(1,1).YData = ydata21;
            else
                app.PlotLine2(1,1).YData = zeros(1,400);
            end
            %plot lect 2 - M2
            if(box22==1)
                app.PlotLine2(2,1).YData = ydata22;
            else
                app.PlotLine2(2,1).YData = zeros(1,400);
            end
            
            clearvars cadena;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            global box11 box12 box21 box22
            box11=0;box12=0;box21=0;box22=0;
            
            mypi=raspi('169.254.111.33','pi','raspberry');
            %app.s = serialdev(mypi,'/dev/serial0',115200); %Serial0 RPI
            app.s = serialdev(mypi,'/dev/ttyAMA1',115200); %Serial5 RPI
            app.s.Timeout = 0.5;
            % Initial plot is all zeros
            line=linspace(0,10000,400);
            app.PlotLine = plot(app.UIAxes,line,line,line,line);
            app.PlotLine2 = plot(app.UIAxes_2,line,line,line,line);
            
            % Create timer object
            app.LectTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.05, ...                     % Period is 50 ms
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.SerialTimerFcn);      % Specify callback function
            

        end

        % Callback function
        function StartButtonPushed(app, event)
%             if strcmp(app.LectTimer.Running, 'off')
%                     start(app.LectTimer);
%             end
        end

        % Callback function
        function StopButtonPushed(app, event)
%             stop(app.LectTimer);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if (value==1)
                if strcmp(app.LectTimer.Running, 'off')
                    start(app.LectTimer);
                end
            end
            
            if (value==0)
                stop(app.LectTimer);
            end
        end

        % Value changed function: M11CheckBox
        function M11CheckBoxValueChanged(app, event)
            global box11  
            value = app.M11CheckBox.Value;
            if value==1
                box11=1;
            else
                box11=0;
            end
        end

        % Value changed function: M21CheckBox
        function M21CheckBoxValueChanged(app, event)
            global box12 
            value = app.M21CheckBox.Value;
            if value==1
                box12=1;
            else
                box12=0;
            end            
        end

        % Value changed function: M12CheckBox
        function M12CheckBoxValueChanged(app, event)
            global box21
            value = app.M12CheckBox.Value;
            if value==1
                box21=1;
            else
                box21=0;
            end 
        end

        % Value changed function: M22CheckBox
        function M22CheckBoxValueChanged(app, event)
            global box22
            value = app.M22CheckBox.Value;
            if value==1
                box22=1;
            else
                box22=0;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'tiempo (ms)')
            ylabel(app.UIAxes, 'Volltaje')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10000];
            app.UIAxes.YLim = [0 3500];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.XTick = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'tiempo (ms)')
            ylabel(app.UIAxes_2, 'Volltaje')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 10000];
            app.UIAxes_2.YLim = [0 3500];
            app.UIAxes_2.XDir = 'reverse';
            app.UIAxes_2.XTick = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.M11CheckBox = uicheckbox(app.UIFigure);
            app.M11CheckBox.ValueChangedFcn = createCallbackFcn(app, @M11CheckBoxValueChanged, true);
            app.M11CheckBox.Text = 'M11';
            app.M21CheckBox = uicheckbox(app.UIFigure);
            app.M21CheckBox.ValueChangedFcn = createCallbackFcn(app, @M21CheckBoxValueChanged, true);
            app.M21CheckBox.Text = 'M21';
            app.M12CheckBox = uicheckbox(app.UIFigure);
            app.M12CheckBox.ValueChangedFcn = createCallbackFcn(app, @M12CheckBoxValueChanged, true);
            app.M12CheckBox.Text = {'M12'; ''};
            app.M22CheckBox = uicheckbox(app.UIFigure);
            app.M22CheckBox.ValueChangedFcn = createCallbackFcn(app, @M22CheckBoxValueChanged, true);
            app.M22CheckBox.Text = 'M22';
            app.DutyCycleLabel = uilabel(app.UIFigure);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleSlider = uislider(app.UIFigure);
            app.MduloSpinnerLabel = uilabel(app.UIFigure);
            app.MduloSpinnerLabel.Text = 'Módulo';
            app.MduloSpinner = uispinner(app.UIFigure);
            app.MduloSpinner.Limits = [1 8];
            app.MduloSpinner.Value = 1;
            app.SealSpinnerLabel = uilabel(app.UIFigure);
            app.SealSpinnerLabel.Text = 'Señal';
            app.SealSpinner = uispinner(app.UIFigure);
            app.SealSpinner.Limits = [1 2];
            app.SealSpinner.Value = 1;
            app.SetparameterButton = uibutton(app.UIFigure, 'push');
            app.SetparameterButton.Text = 'Set parameter';
            app.M31CheckBox = uicheckbox(app.UIFigure);
            app.M31CheckBox.Text = 'M31';
            app.M41CheckBox = uicheckbox(app.UIFigure);
            app.M41CheckBox.Text = 'M41';
            app.M51CheckBox = uicheckbox(app.UIFigure);
            app.M51CheckBox.Text = 'M51';
            app.M61CheckBox = uicheckbox(app.UIFigure);
            app.M61CheckBox.Text = 'M61';
            app.M71CheckBox = uicheckbox(app.UIFigure);
            app.M71CheckBox.Text = 'M71';
            app.M81CheckBox = uicheckbox(app.UIFigure);
            app.M81CheckBox.Text = 'M81';
            app.M32CheckBox = uicheckbox(app.UIFigure);
            app.M32CheckBox.Text = {'M32'; ''};
            app.M42CheckBox = uicheckbox(app.UIFigure);
            app.M42CheckBox.Text = {'M42'; ''};
            app.M52CheckBox = uicheckbox(app.UIFigure);
            app.M52CheckBox.Text = {'M52'; ''};
            app.M62CheckBox = uicheckbox(app.UIFigure);
            app.M62CheckBox.Text = {'M62'; ''};
            app.M72CheckBox = uicheckbox(app.UIFigure);
            app.M72CheckBox.Text = {'M72'; ''};
            app.M82CheckBox = uicheckbox(app.UIFigure);
            app.M82CheckBox.Text = {'M82'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'mascara_interfaz.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_timer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end